classdef app1_autoreflow2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        LineSpacingPanel           matlab.ui.container.Panel
        d3EditField                matlab.ui.control.EditField
        d3EditFieldLabel           matlab.ui.control.Label
        d2EditField                matlab.ui.control.EditField
        d2EditFieldLabel           matlab.ui.control.Label
        d1EditField                matlab.ui.control.EditField
        d1EditFieldLabel           matlab.ui.control.Label
        LinespacingDropDown        matlab.ui.control.DropDown
        LinespacingDropDownLabel   matlab.ui.control.Label
        LinespecificationsPanel    matlab.ui.container.Panel
        ResistivityEditField       matlab.ui.control.EditField
        ResistivityEditFieldLabel  matlab.ui.control.Label
        DiameterEditField          matlab.ui.control.EditField
        DiameterEditFieldLabel     matlab.ui.control.Label
        LengthEditField            matlab.ui.control.EditField
        LengthEditFieldLabel       matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        ABCDparametersPanel        matlab.ui.container.Panel
        AEditField_5               matlab.ui.control.EditField
        AEditField_5Label          matlab.ui.control.Label
        RCLvaluesPanel             matlab.ui.container.Panel
        CEditField                 matlab.ui.control.EditField
        CEditFieldLabel            matlab.ui.control.Label
        DEditField                 matlab.ui.control.EditField
        DEditFieldLabel            matlab.ui.control.Label
        BEditField                 matlab.ui.control.EditField
        BEditFieldLabel            matlab.ui.control.Label
        InductanceEditField        matlab.ui.control.EditField
        InductanceEditFieldLabel   matlab.ui.control.Label
        CapacitanceEditField       matlab.ui.control.EditField
        CapacitanceEditFieldLabel  matlab.ui.control.Label
        ResistanceEditField        matlab.ui.control.EditField
        ResistanceEditFieldLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        editpose % original position of line spacing dropdown menu
        labelpose % original position of line spacing dropdown menu label
        panelpose % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LinespacingDropDown.Items = ["Symmetrical" "Unsymmetrical"];
            app.d1EditField.Visible = false;
            app.d1EditFieldLabel.Visible = false;
            app.d1EditField.Enable = false;
            app.d1EditFieldLabel.Enable = false;
            app.d2EditField.Visible = false;
            app.d2EditFieldLabel.Visible = false;
            app.d2EditField.Enable = false;
            app.d2EditFieldLabel.Enable = false;
            app.d3EditField.Visible = false;
            app.d3EditFieldLabel.Visible = false;
            app.d3EditField.Enable = false;
            app.d3EditFieldLabel.Enable = false;
             app.editpose = app.LinespacingDropDown.Position;
             app.labelpose = app.LinespacingDropDownLabel.Position;
             label = app.labelpose;
             app.LinespacingDropDown.Position = app.d3EditField.Position;
             app.LinespacingDropDownLabel.Position = app.d3EditFieldLabel.Position;
             app.LinespacingDropDownLabel.Position(3) = label(3);
             app.panelpose = app.LineSpacingPanel.Position;
             pose = app.panelpose;
             pose(4) = app.LinespacingDropDown.Position(4)*2.5;
             app.LineSpacingPanel.Position = pose;
        end

        % Callback function
        function LengthEditFieldValueChanging(app, event)
            app.LengthEditField.FontColor = [0.0, 0.0, 0.0];
        end

        % Callback function
        function DiameterEditFieldValueChanging(app, event)
            app.DiameterEditField.FontColor = [0.0, 0.0, 0.0];
        end

        % Callback function
        function ResistivityEditFieldValueChanging(app, event)
            app.ResistivityEditField.FontColor = [0.0, 0.0, 0.0];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {527, 527};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {335, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: LinespacingDropDown
        function LinespacingDropDownValueChanged(app, event)
            value = app.LinespacingDropDown.Value;
            if strcmpi(value, 'Unsymmetrical')
                app.LinespacingDropDown.Position = app.editpose;
                app.LinespacingDropDownLabel.Position = app.labelpose;
                app.LineSpacingPanel.Position = app.panelpose;
                app.d1EditField.Visible = true;
                app.d1EditFieldLabel.Visible = true;
                app.d1EditField.Enable = true;
                app.d1EditFieldLabel.Enable = true;
                app.d2EditField.Visible = true;
                app.d2EditFieldLabel.Visible = true;
                app.d2EditField.Enable = true;
                app.d2EditFieldLabel.Enable = true;
                app.d3EditField.Visible = true;
                app.d3EditFieldLabel.Visible = true;
                app.d3EditField.Enable = true;
                app.d3EditFieldLabel.Enable = true;
        else
            app.LinespacingDropDown.Position = app.editpose;
                app.LinespacingDropDownLabel.Position = app.labelpose;
                app.LineSpacingPanel.Position = app.panelpose;
                app.d1EditField.Visible = true;
                app.d1EditFieldLabel.Visible = true;
                app.d1EditField.Enable = true;
                app.d1EditFieldLabel.Enable = true;
                app.d2EditField.Visible = true;
                app.d2EditFieldLabel.Visible = true;
                app.d2EditField.Enable = true;
                app.d2EditFieldLabel.Enable = true;
                app.d3EditField.Visible = true;
                app.d3EditFieldLabel.Visible = true;
                app.d3EditField.Enable = true;
                app.d3EditFieldLabel.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LinespecificationsPanel = uipanel(app.LeftPanel);
            app.LinespecificationsPanel.Title = 'Line specifications';
            app.LengthEditFieldLabel = uilabel(app.LinespecificationsPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.LinespecificationsPanel, 'text');
            app.LengthEditField.Placeholder = 'length in km';
            app.DiameterEditFieldLabel = uilabel(app.LinespecificationsPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.LinespecificationsPanel, 'text');
            app.DiameterEditField.Placeholder = 'diameter in cm';
            app.ResistivityEditFieldLabel = uilabel(app.LinespecificationsPanel);
            app.ResistivityEditFieldLabel.Text = 'Resistivity';
            app.ResistivityEditField = uieditfield(app.LinespecificationsPanel, 'text');
            app.ResistivityEditField.Placeholder = 'resistivity in Ω⋅m';
            app.LineSpacingPanel = uipanel(app.LeftPanel);
            app.LineSpacingPanel.Title = 'Line Spacing';
            app.LinespacingDropDownLabel = uilabel(app.LineSpacingPanel);
            app.LinespacingDropDownLabel.Text = 'Line spacing';
            app.LinespacingDropDown = uidropdown(app.LineSpacingPanel);
            app.LinespacingDropDown.Items = {};
            app.LinespacingDropDown.ValueChangedFcn = createCallbackFcn(app, @LinespacingDropDownValueChanged, true);
            app.LinespacingDropDown.Placeholder = 'Line spacing';
            app.LinespacingDropDown.Value = {};
            app.d1EditFieldLabel = uilabel(app.LineSpacingPanel);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.LineSpacingPanel, 'text');
            app.d1EditField.Placeholder = 'Distance between a and b (in m)';
            app.d2EditFieldLabel = uilabel(app.LineSpacingPanel);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.LineSpacingPanel, 'text');
            app.d2EditField.Placeholder = 'Distance between b and c (in m)';
            app.d3EditFieldLabel = uilabel(app.LineSpacingPanel);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.LineSpacingPanel, 'text');
            app.d3EditField.Placeholder = 'Distance between c and a (in m)';
            app.RightPanel = uipanel(app.GridLayout);
            app.RCLvaluesPanel = uipanel(app.RightPanel);
            app.RCLvaluesPanel.Title = 'RCL values';
            app.ResistanceEditFieldLabel = uilabel(app.RCLvaluesPanel);
            app.ResistanceEditFieldLabel.Text = 'Resistance';
            app.ResistanceEditField = uieditfield(app.RCLvaluesPanel, 'text');
            app.CapacitanceEditFieldLabel = uilabel(app.RCLvaluesPanel);
            app.CapacitanceEditFieldLabel.Text = 'Capacitance';
            app.CapacitanceEditField = uieditfield(app.RCLvaluesPanel, 'text');
            app.InductanceEditFieldLabel = uilabel(app.RCLvaluesPanel);
            app.InductanceEditFieldLabel.Text = 'Inductance';
            app.InductanceEditField = uieditfield(app.RCLvaluesPanel, 'text');
            app.ABCDparametersPanel = uipanel(app.RightPanel);
            app.ABCDparametersPanel.Title = 'ABCD parameters';
            app.BEditFieldLabel = uilabel(app.ABCDparametersPanel);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.ABCDparametersPanel, 'text');
            app.DEditFieldLabel = uilabel(app.ABCDparametersPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.ABCDparametersPanel, 'text');
            app.CEditFieldLabel = uilabel(app.ABCDparametersPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.ABCDparametersPanel, 'text');
            app.AEditField_5Label = uilabel(app.ABCDparametersPanel);
            app.AEditField_5Label.Text = 'A';
            app.AEditField_5 = uieditfield(app.ABCDparametersPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_autoreflow2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end